classdef appMetNum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        tabPaneApp                      matlab.ui.container.TabGroup
        RacesdeunafuncinTab             matlab.ui.container.Tab
        a_2                             matlab.ui.control.EditField
        AproximacinInicialLabel_6       matlab.ui.control.Label
        b_2                             matlab.ui.control.EditField
        AproximacinInicialLabel_5       matlab.ui.control.Label
        b                               matlab.ui.control.EditField
        AproximacinInicialLabel_4       matlab.ui.control.Label
        a                               matlab.ui.control.EditField
        AproximacinInicialLabel_3       matlab.ui.control.Label
        RegulaFalsiLabel                matlab.ui.control.Label
        BiseccinLabel                   matlab.ui.control.Label
        xi_menos_1                      matlab.ui.control.EditField
        ToleranciaLabel_3               matlab.ui.control.Label
        xi                              matlab.ui.control.EditField
        AproximacinInicialLabel_2       matlab.ui.control.Label
        SecanteLabel                    matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        maxIter                         matlab.ui.control.EditField
        ToleranciaLabel_2               matlab.ui.control.Label
        tol                             matlab.ui.control.EditField
        ToleranciaLabel                 matlab.ui.control.Label
        NewtonRaphsonLabel              matlab.ui.control.Label
        x0                              matlab.ui.control.EditField
        AproximacinInicialLabel         matlab.ui.control.Label
        funcion                         matlab.ui.control.EditField
        FuncinEditFieldLabel            matlab.ui.control.Label
        EncontrarlasracesdeLabel        matlab.ui.control.Label
        graficaFuncion                  matlab.ui.control.UIAxes
        SIstemasdeecuacineslinealesTab  matlab.ui.container.Tab
        DerivadaNumricaTab              matlab.ui.container.Tab
        OrdenDropDown                   matlab.ui.control.DropDown
        OrdenDropDownLabel              matlab.ui.control.Label
        CentralLabel                    matlab.ui.control.Label
        RegresivaLabel                  matlab.ui.control.Label
        ProgresivaLabel                 matlab.ui.control.Label
        CalcularDerivadasButton         matlab.ui.control.Button
        hEditField                      matlab.ui.control.NumericEditField
        hEditFieldLabel                 matlab.ui.control.Label
        xiEditField                     matlab.ui.control.NumericEditField
        xiEditFieldLabel                matlab.ui.control.Label
        FuncionEditField                matlab.ui.control.EditField
        FuncionLabel                    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        IntegracinNumricaTab            matlab.ui.container.Tab
        n_intervalo                     matlab.ui.control.NumericEditField
        LmiteinferiorbLabel_2           matlab.ui.control.Label
        LmiteinferiorbLabel             matlab.ui.control.Label
        b_limite                        matlab.ui.control.NumericEditField
        a_limite                        matlab.ui.control.NumericEditField
        LmiteinferioraEditFieldLabel    matlab.ui.control.Label
        funcion_integral                matlab.ui.control.EditField
        AproximacinInicialLabel_7       matlab.ui.control.Label
        resultadoLabel                  matlab.ui.control.Label
        label                           matlab.ui.control.Label
        CalcularporelmtododeLabel       matlab.ui.control.Label
        CalcularRectanguloButton        matlab.ui.control.Button
        CalcularSimpsonButton           matlab.ui.control.Button
        CalcularTrapecioButton          matlab.ui.control.Button
        IntegracinnumricaLabel          matlab.ui.control.Label
        graficoTrapecio                 matlab.ui.control.UIAxes
        EcuacionesDiferencialesTab      matlab.ui.container.Tab
        x_final_2                       matlab.ui.control.EditField
        HastaquexEditFieldLabel_2       matlab.ui.control.Label
        h_2                             matlab.ui.control.EditField
        hEditField_2Label_2             matlab.ui.control.Label
        y0euler                         matlab.ui.control.EditField
        y0EditFieldLabel_2              matlab.ui.control.Label
        x0euler                         matlab.ui.control.EditField
        x0EditFieldLabel_2              matlab.ui.control.Label
        funcionEDO                      matlab.ui.control.EditField
        FuncinfxyEditFieldLabel_2       matlab.ui.control.Label
        EulerTable                      matlab.ui.control.Table
        CalcularEulerButton             matlab.ui.control.Button
        EulerLabel                      matlab.ui.control.Label
        EulerMejoradoTable              matlab.ui.control.Table
        CalcularEulerMejoradoButton     matlab.ui.control.Button
        x_final                         matlab.ui.control.EditField
        HastaquexEditFieldLabel         matlab.ui.control.Label
        h                               matlab.ui.control.EditField
        hEditField_2Label               matlab.ui.control.Label
        y0heun                          matlab.ui.control.EditField
        y0EditFieldLabel                matlab.ui.control.Label
        x0heun                          matlab.ui.control.EditField
        x0EditFieldLabel                matlab.ui.control.Label
        EulerMejoradoHeunLabel          matlab.ui.control.Label
        edo                             matlab.ui.control.EditField
        FuncinfxyEditFieldLabel         matlab.ui.control.Label
        EulerAxes                       matlab.ui.control.UIAxes
        EulerMejoradoAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % --- Configurar variable simbólica y entrada del usuario ---
            syms x
            f = str2sym(app.funcion.Value);                      % Convierte el texto en expresión simbólica
            df = diff(f, x);                               % Deriva simbólicamente
            % --- Convertir funciones simbólicas a anónimas ---
            f_anon = matlabFunction(f);                    % @(x) ...
            df_anon = matlabFunction(df);                  % @(x) ...
            
            % --- Obtener y convertir valores numéricos de los campos de la GUI ---
            % ¡Asegúrate de que estas conversiones se hacen antes de usarlas!
            x0_numerico = str2double(app.x0.Value); % Aproximación inicial para Newton-Raphson
            tol_numerico = str2double(app.tol.Value); % Tolerancia para todos los métodos
            
            maxIter_texto = app.maxIter.Value;
            maxIter_numerico_double = str2double(maxIter_texto);
            
            % 3. Validar que sea un número entero positivo
            if isnan(maxIter_numerico_double) || maxIter_numerico_double <= 0 || mod(maxIter_numerico_double, 1) ~= 0
                uialert(app.UIFigure, 'El "Máximo de Iteraciones" debe ser un número entero positivo. Por favor, corrija la entrada.', 'Error de Entrada');
                return; % Detiene la ejecución del callback si hay error
            end
            maxIter_entero = int64(maxIter_numerico_double); 
            
            % --- Ejecutar Newton-Raphson ---
            [raiz_newton, iteraciones_newton, error_relativo_newton] = newtonRaphson(f_anon, df_anon, x0_numerico, tol_numerico, maxIter_entero);
            
            % --- Obtener valores para el método de la secante ---
            x_i_secante = str2double(app.xi.Value); % Segunda aproximación inicial para Secante
            xi_1_secante = str2double(app.xi_menos_1.Value); % Primera aproximación inicial para Secante
            % --- Ejecutar Método de la Secante ---
            % Nota: La función metodoSecante no recibe df_anon.
            % Asegúrate de que tu función metodoSecante solo espera 5 argumentos:
            % (funcion_anonima, x_i_menos_1, x_i, tolerancia, max_iteraciones)
            [raiz_secante, iteraciones_secante, error_relativo_secante] = metodoSecante(f_anon, xi_1_secante, x_i_secante, tol_numerico, maxIter_entero);
            
            % --- Obtener valores para el método de la bisección ---
            a_biseccion = str2double(app.a.Value); % Límite inferior para Bisección
            b_biseccion = str2double(app.b.Value); % Límite superior para Bisección
            % --- Ejecutar Método de Bisección ---
            % Asegúrate de que tu función biseccion solo espera 5 argumentos:
            % (funcion_anonima, a, b, tolerancia, max_iteraciones)
            [raiz_biseccion, iteraciones_biseccion, error_relativo_biseccion] = biseccion(f_anon, a_biseccion, b_biseccion, tol_numerico, maxIter_entero);
            
            % --- Obtener valores para el método de Regula Falsi ---
            % Asumo que 'app.a_2' y 'app.b_2' son los EditFields para los límites del intervalo de Regula Falsi
            a_falsi = str2double(app.a_2.Value); 
            b_falsi = str2double(app.b_2.Value);
            
            % --- Ejecutar Método de Regula Falsi ---
            % Asegúrate de que tu función regula_falsi espera 5 argumentos:
            % (funcion_anonima, a, b, tolerancia, max_iteraciones)
            [raiz_falsi, iteraciones_falsi, error_relativo_falsi] = regula_falsi(f_anon, a_falsi, b_falsi, tol_numerico, maxIter_entero);
            
            % --- Graficar función ---
            cla(app.graficaFuncion)                       % Limpia el gráfico anterior
            
            % Rango de la gráfica: ajustado para incluir todas las raíces y un poco más
            % ¡ACTUALIZADO para incluir raiz_falsi en el cálculo del rango!
            min_x_plot = min([x0_numerico - 10, raiz_newton - 2, raiz_secante - 2, raiz_biseccion - 2, raiz_falsi - 2]);
            max_x_plot = max([x0_numerico + 10, raiz_newton + 2, raiz_secante + 2, raiz_biseccion + 2, raiz_falsi + 2]);
            fplot(app.graficaFuncion, f, [min_x_plot, max_x_plot], 'LineWidth', 1.5, 'DisplayName', 'f(x)')
            hold(app.graficaFuncion, 'on')
            
            % --- Marcar y etiquetar raíz de Newton-Raphson ---
            y_raiz_newton = f_anon(raiz_newton);
            plot(app.graficaFuncion, raiz_newton, y_raiz_newton, 'ro', 'MarkerSize', 8, 'LineWidth', 2, 'DisplayName', 'Raíz Newton-Raphson')
            text(app.graficaFuncion, raiz_newton, y_raiz_newton, sprintf('  Newton-Raphson (%.5f)', raiz_newton), ...
                 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left', 'Color', 'red', 'FontSize', 15);
            
            % --- Marcar y etiquetar raíz de la Secante ---
            y_raiz_secante = f_anon(raiz_secante);
            plot(app.graficaFuncion, raiz_secante, y_raiz_secante, 'bs', 'MarkerSize', 8, 'LineWidth', 2, 'DisplayName', 'Raíz Secante')
            text(app.graficaFuncion, raiz_secante, y_raiz_secante, sprintf('  Secante (%.5f)', raiz_secante), ...
                 'VerticalAlignment', 'top', 'HorizontalAlignment', 'left', 'Color', 'blue', 'FontSize', 15);
            
            % --- Marcar y etiquetar raíz de la Bisección ---
            y_raiz_biseccion = f_anon(raiz_biseccion);
            plot(app.graficaFuncion, raiz_biseccion, y_raiz_biseccion, 'gd', 'MarkerSize', 8, 'LineWidth', 2, 'DisplayName', 'Raíz Bisección') % 'gd' para un diamante verde
            text(app.graficaFuncion, raiz_biseccion, y_raiz_biseccion, sprintf('  Bisección (%.5f)', raiz_biseccion), ...
                 'VerticalAlignment', 'middle', 'HorizontalAlignment', 'right', 'Color', 'magenta', 'FontSize', 15); % Ajusta la alineación si se superpone
            
            % --- Marcar y etiquetar raíz de Regula Falsi ---
            y_raiz_falsi = f_anon(raiz_falsi);
            plot(app.graficaFuncion, raiz_falsi, y_raiz_falsi, 'c^', 'MarkerSize', 8, 'LineWidth', 2, 'DisplayName', 'Raíz Regula Falsi') % 'c^' para un triángulo cian
            text(app.graficaFuncion, raiz_falsi, y_raiz_falsi, sprintf('  Regula Falsi (%.5f)', raiz_falsi), ...
                 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', 'Color', 'black', 'FontSize', 15);
            
            % --- Estética del gráfico ---
            grid(app.graficaFuncion, 'on')
            title(app.graficaFuncion, 'Función y Raíces Aproximadas (Newton-Raphson, Secante, Bisección y Regula Falsi)') % Título actualizado
            xlabel(app.graficaFuncion, 'x')
            ylabel(app.graficaFuncion, 'f(x)')
            legend(app.graficaFuncion, 'show', 'Location', 'best'); % Muestra la leyenda
        end

        % Button pushed function: CalcularDerivadasButton
        function CalcularDerivadasButtonPushed(app, event)
            try
        % Llamar función externa
        [dp, dr, dc] = calcularDerivadas( ...
            app.FuncionEditField.Value, ...
            app.xiEditField.Value, ...
            app.hEditField.Value, ...
            str2double(app.OrdenDropDown.Value));
    catch ME
        uialert(app.UIFigure, ME.message, 'Error');
        return;
    end
    % Mostrar resultados
    app.ProgresivaLabel.Text = ['Progresiva ≈ ', num2str(dp)];
    app.RegresivaLabel.Text  = ['Regresiva ≈ ', num2str(dr)];
    app.CentralLabel.Text    = ['Central ≈ ', num2str(dc)];
    % Graficar en UIAxes
    graficarDerivada(app, app.FuncionEditField.Value, app.xiEditField.Value, app.hEditField.Value, str2double(app.OrdenDropDown.Value));
%% funcion Priv Grafica
function graficarDerivada(app, f_str, xi, h, orden)
    f = str2func(['@(x)', f_str]);
    x_vals = linspace(xi - 0.5, xi + 0.5, 200);
    dp_vals = zeros(size(x_vals));
    dr_vals = zeros(size(x_vals));
    dc_vals = zeros(size(x_vals));
    d_exact_vals = zeros(size(x_vals));
    for k = 1:length(x_vals)
        x = x_vals(k);
        switch orden
            case 1
                dp_vals(k) = (f(x + h) - f(x)) / h;
                dr_vals(k) = (f(x) - f(x - h)) / h;
                dc_vals(k) = (f(x + h) - f(x - h)) / (2*h);
                d_exact_vals(k) = cos(x); % Para sin(x)
            case 2
                dp_vals(k) = (f(x + 2*h) - 2*f(x + h) + f(x)) / h^2;
                dr_vals(k) = (f(x) - 2*f(x - h) + f(x - 2*h)) / h^2;
                dc_vals(k) = (f(x + h) - 2*f(x) + f(x - h)) / h^2;
                d_exact_vals(k) = -sin(x); % Para sin(x)
            case 3
                dp_vals(k) = (f(x + 3*h) - 3*f(x + 2*h) + 3*f(x + h) - f(x)) / h^3;
                dr_vals(k) = (f(x) - 3*f(x - h) + 3*f(x - 2*h) - f(x - 3*h)) / h^3;
                dc_vals(k) = (f(x + 2*h) - 2*f(x + h) + 2*f(x - h) - f(x - 2*h)) / (2*h^3);
                d_exact_vals(k) = -cos(x); % Para sin(x)
        end
    end
    % Limpiar axes
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');
    % Graficar función original
    plot(app.UIAxes, x_vals, f(x_vals), 'y-', 'LineWidth', 1);
    % Graficar derivada exacta y aproximaciones
    plot(app.UIAxes, x_vals, d_exact_vals, 'b-', 'LineWidth', 2);
    plot(app.UIAxes, x_vals, dp_vals, 'm--', 'LineWidth', 1.5);
    plot(app.UIAxes, x_vals, dr_vals, 'g--', 'LineWidth', 1.5);
    plot(app.UIAxes, x_vals, dc_vals, 'r--', 'LineWidth', 2);
    % Configuración
    legend(app.UIAxes, {'f(x)', 'Exacta', 'Progresiva', 'Regresiva', 'Central'}, 'Location', 'best');
    xlabel(app.UIAxes, 'x');
    ylabel(app.UIAxes, 'Valor');
    title(app.UIAxes, ['Derivada de orden ', num2str(orden)]);
    grid(app.UIAxes, 'on');
    hold(app.UIAxes, 'off');
end
        end

        % Button pushed function: CalcularSimpsonButton
        function CalcularSimpsonButtonPushed(app, event)
            try
                % Obtener función como string y convertir a función anónima
                func_str = app.funcion_integral.Value;
                f = str2func(['@(x)', func_str]);
            
                % Obtener límites y número de subintervalos
                a = app.a_limite.Value;
                b = app.b_limite.Value;
                n = app.n_intervalo.Value;
            
                % Validar que n sea par
                if mod(n, 2) ~= 0
                    uialert(app.UIFigure, 'El número de intervalos debe ser par para usar la regla de Simpson 1/3.', 'Error');
                    return;
                end
            
                % Calcular resultado con Simpson 1/3
                resultado = simpson13(f, a, b, n);
                app.resultadoLabel.Text = [num2str(resultado)];
            
                % Graficar función y aproximación
                cla(app.graficoTrapecio);
                hold(app.graficoTrapecio, 'on');
            
                % Gráfica de la función
                x_plot = linspace(a, b, 1000);
                y_plot = f(x_plot);
                plot(app.graficoTrapecio, x_plot, y_plot, 'b', 'LineWidth', 2);
            
                % Dibujar parábolas con sombreado
                x_vals = linspace(a, b, n+1); % n subintervalos => n+1 puntos
                y_vals = f(x_vals);
            
                for i = 1:2:n-1
                    xi = x_vals(i:i+2);
                    yi = y_vals(i:i+2);
            
                    % Ajustar parábola
                    coef = polyfit(xi, yi, 2);
                    xx = linspace(xi(1), xi(3), 50);
                    yy = polyval(coef, xx);
            
                    % Sombreado con opacidad y líneas divisorias visibles
                    fill(app.graficoTrapecio, [xx fliplr(xx)], [yy zeros(size(yy))], ...
                        [0.8 0.6 1], 'FaceAlpha', 0.3, 'EdgeColor', 'k');
                end
            
                title(app.graficoTrapecio, 'Aproximación con regla de Simpson');
                xlabel(app.graficoTrapecio, 'x');
                ylabel(app.graficoTrapecio, 'f(x)');
                grid(app.graficoTrapecio, 'on');
                hold(app.graficoTrapecio, 'off');
            
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Callback function: CalcularTrapecioButton, IntegracinNumricaTab
        function CalcularTrapecioButtonPushed(app, event)
            try
                % Obtener datos desde la interfaz
                f_str = app.funcion_integral.Value;
                a = app.a_limite.Value;
                b = app.b_limite.Value;
                N = app.n_intervalo.Value;  % Asegúrate que sea el mismo para todos
        
                % Validaciones
                if a >= b
                    error('El límite inferior debe ser menor que el superior.');
                end
                if N <= 0 || mod(N,1) ~= 0
                    error('El número de subintervalos debe ser un entero positivo.');
                end
        
                % Conversión de función
                f = str2func(['@(x)', f_str]);
        
                % Cálculo del resultado usando método del trapecio
                h = (b - a) / N;
                x = linspace(a, b, N+1);
                y = f(x);
                resultado = (h / 2) * (y(1) + 2 * sum(y(2:end-1)) + y(end));
        
                % Gráfica
                cla(app.graficoTrapecio);
                hold(app.graficoTrapecio, 'on');
        
                % Gráfico de la función
                x_plot = linspace(a, b, 500);
                plot(app.graficoTrapecio, x_plot, f(x_plot), 'b', 'LineWidth', 2);
        
                % Rellenar trapecios
                for i = 1:N
                    fill(app.graficoTrapecio, ...
                        [x(i), x(i+1), x(i+1), x(i)], ...
                        [0, 0, y(i+1), y(i)], ...
                        [1.0 0.75 0.8], 'FaceAlpha', 0.3, 'EdgeColor', 'k');
                end
        
                % Detalles del gráfico
                title(app.graficoTrapecio, 'Método del Trapecio');
                xlabel(app.graficoTrapecio, 'x');
                ylabel(app.graficoTrapecio, 'f(x)');
                grid(app.graficoTrapecio, 'on');
                hold(app.graficoTrapecio, 'off');
        
                % Mostrar resultado
                app.resultadoLabel.Text = ['Resultado (Trapecio): ', num2str(resultado)];
        
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: CalcularRectanguloButton
        function CalcularRectanguloButtonPushed(app, event)
            try
                f_str = app.funcion_integral.Value;
                a = app.a_limite.Value;
                b = app.b_limite.Value;
                N = app.n_intervalo.Value;  % Asegúrate de usar el campo correcto
        
                if a >= b
                    error('El límite inferior debe ser menor que el superior.');
                end
                if N <= 0 || mod(N,1) ~= 0
                    error('El número de subintervalos debe ser un entero positivo.');
                end
        
                f = str2func(['@(x)', f_str]);
                h = (b - a) / N;
                x = linspace(a, b - h, N);
                y = f(x);
                resultado = h * sum(y);
        
                % Gráfica
                cla(app.graficoTrapecio);
                hold(app.graficoTrapecio, 'on');
                for i = 1:N
                    rectangle('Parent', app.graficoTrapecio, ...
                              'Position', [x(i), 0, h, y(i)], ...
                              'FaceColor', [0.7 0.9 1], ...
                              'EdgeColor', 'k');
                end
                x_plot = linspace(a, b, 500);
                plot(app.graficoTrapecio, x_plot, f(x_plot), 'b', 'LineWidth', 2);
                title(app.graficoTrapecio, 'Método de Rectángulos');
                xlabel(app.graficoTrapecio, 'x');
                ylabel(app.graficoTrapecio, 'f(x)');
                grid(app.graficoTrapecio, 'on');
                hold(app.graficoTrapecio, 'off');
        
                % Mostrar resultado
                app.resultadoLabel.Text = [num2str(resultado)];
                
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: CalcularEulerMejoradoButton
        function CalcularEulerMejorado(app, event)
               try
        % 1. Get input values from GUI components
        func_str = app.edo.Value;
        x0_val = str2double(app.x0heun.Value);
        y0_val = str2double(app.y0heun.Value);
        h_val = str2double(app.h.Value);
        xn_val = str2double(app.x_final.Value);

        % 2. Validate inputs before proceeding
        if isempty(func_str)
            uialert(app.UIFigure, 'Por favor, ingrese la función f(x, y).', 'Error de Entrada');
            return;
        end
        if isnan(x0_val) || isnan(y0_val) || isnan(h_val) || isnan(xn_val)
            uialert(app.UIFigure, 'Todos los valores de entrada (x0, y0, h, xn) deben ser números válidos.', 'Error de Entrada');
            return;
        end
        if h_val <= 0
            uialert(app.UIFigure, 'El tamaño de paso (h) debe ser un número positivo.', 'Error de Entrada');
            return;
        end
        if xn_val <= x0_val
            uialert(app.UIFigure, 'El valor final de x (xn) debe ser mayor que el valor inicial (x0).', 'Error de Entrada');
            return;
        end
        
        % 3. Convert function string to anonymous function handle
        %    (Requires Symbolic Math Toolbox for str2sym and matlabFunction)
        %    NOTE: For f(x,y), matlabFunction needs specific order, or directly create handle
        try
            % This approach handles 'x' and 'y' as symbolic variables
            syms x y
            f_sym = str2sym(func_str);
            % Ensure the anonymous function takes two arguments: x and y
            f_anon = matlabFunction(f_sym, 'Vars', {x, y}); 
        catch
            uialert(app.UIFigure, 'Error al interpretar la función f(x, y). Asegúrese de usar ''x'' e ''y'' como variables.', 'Error de Función');
            return;
        end

        % 4. Call the Improved Euler method function
        [x_result, y_result, result_table] = euler_mejorado(f_anon, x0_val, y0_val, h_val, xn_val, app);

        % 5. Display results in GUI components
        app.EulerMejoradoTable.Data = result_table; % Assign the table data

        % 6. Plot the approximated solution
        cla(app.EulerMejoradoAxes); % Clear previous plot
        plot(app.EulerMejoradoAxes, x_result, y_result, 'o-', 'LineWidth', 2, 'Color', [0.85 0.33 0.1]); % Orange color
        title(app.EulerMejoradoAxes, 'Solución Aproximada');
        xlabel(app.EulerMejoradoAxes, 'x');
        ylabel(app.EulerMejoradoAxes, 'y');
        grid(app.EulerMejoradoAxes, 'on');

    catch ME
        % Handle any errors that occur during execution
        uialert(app.UIFigure, ME.message, 'Error de Ejecución');
        % Clear outputs if an error occurs
        app.EulerMejoradoTable.Data = {};
        cla(app.EulerMejoradoAxes);
    end
        end

        % Button pushed function: CalcularEulerButton
        function CalcularEulerButtonPushed(app, event)
            try
        % 1. Obtener datos desde la interfaz, usando los nombres de componentes que mencionaste
        f_str = app.funcionEDO.Value;        % Nombre del campo de texto para la función f(x, y)
        x0_val = str2double(app.x0euler.Value);  % Nombre del campo de texto para x0
        y0_val = str2double(app.y0euler.Value);  % Nombre del campo de texto para y0
        h_val = str2double(app.h_2.Value);       % Nombre del campo de texto para el paso h
        xn_val = str2double(app.x_final_2.Value); % Nombre del campo de texto para x_final
        
        % 2. Validaciones de entrada iniciales (antes de llamar a la función euler_gui)
        if isempty(f_str)
            uialert(app.UIFigure, 'Por favor, ingrese la función f(x, y).', 'Error de Entrada');
            return;
        end
        if isnan(x0_val) || isnan(y0_val) || isnan(h_val) || isnan(xn_val)
            uialert(app.UIFigure, 'Todos los valores de entrada (x0, y0, h, xn) deben ser números válidos.', 'Error de Entrada');
            return;
        end
        if h_val <= 0
            uialert(app.UIFigure, 'El tamaño de paso (h) debe ser un número positivo.', 'Error de Entrada');
            return;
        end
        if xn_val < x0_val 
           uialert(app.UIFigure, 'El valor final de x (xn) debe ser mayor o igual que el valor inicial (x0).', 'Error de Entrada');
           return;
        end
        
        % 3. Convertir cadena a función anónima f(x, y)
        try
            syms x y
            f_sym = str2sym(f_str);
            % Asegúrate de que la función anónima tome dos argumentos: x e y
            f_anon = matlabFunction(f_sym, 'Vars', {x, y}); 
        catch
            uialert(app.UIFigure, 'Error al interpretar la función f(x, y). Asegúrese de usar ''x'' e ''y'' como variables.', 'Error de Función');
            return;
        end
        
        % 4. Llamar a la función del Método de Euler (euler_gui)
        % Aquí pasamos los valores numéricos obtenidos de la GUI.
        % 'h_val' y 'xn_val' son las variables del callback que corresponden a 'h_2' y 'x_final_2'.
        [x_result, y_result, result_table] = euler(f_anon, x0_val, y0_val, h_val, xn_val, app);
        
        % 5. Mostrar resultados en los componentes de la GUI
        app.EulerTable.Data = result_table; % Asignar la tabla de resultados
        
        % Opcional: Actualizar etiquetas con el valor final
        if ~isempty(x_result) && ~isempty(y_result) % Asegurarse de que hay resultados
            % Asegúrate de tener estas etiquetas en tu GUI (ej. uilabel)
            % app.XFinalEulerLabel.Text = sprintf('x Final: %.4f', x_result(end));
            % app.YFinalEulerLabel.Text = sprintf('y Final: %.6f', y_result(end));
        else
            % app.XFinalEulerLabel.Text = 'x Final: N/A';
            % app.YFinalEulerLabel.Text = 'y Final: N/A';
        end

        % 6. Graficar la solución aproximada
        cla(app.EulerAxes); % Limpiar gráfica anterior
        
        if ~isempty(x_result) && ~isempty(y_result) % Solo graficar si hay datos
            plot(app.EulerAxes, x_result, y_result, 'o-', 'LineWidth', 2, 'Color', [0.2 0.6 1], ...
                 'DisplayName', 'Aproximación por Euler Simple'); % Azul pastel, con DisplayName
            
            title(app.EulerAxes, 'Método de Euler Simple: Solución Aproximada');
            xlabel(app.EulerAxes, 'x');
            ylabel(app.EulerAxes, 'y');
            grid(app.EulerAxes, 'on');
            legend(app.EulerAxes, 'show', 'Location', 'best'); % La leyenda leerá el DisplayName
        else
            title(app.EulerAxes, 'Método de Euler Simple: No hay datos para graficar');
        end
        
    catch ME
        % 7. Manejo de errores
        uialert(app.UIFigure, ME.message, 'Error en ejecución');
        % Limpiar salidas en caso de error
        app.EulerTable.Data = {}; % Vaciar la tabla
        cla(app.EulerAxes);       % Limpiar la gráfica
        % app.XFinalEulerLabel.Text = 'x Final: N/A'; % Opcional: limpiar etiquetas finales
        % app.YFinalEulerLabel.Text = 'y Final: N/A';
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.tabPaneApp = uitabgroup(app.UIFigure);
            app.tabPaneApp.Tag = 'tabPaneApp';
            app.RacesdeunafuncinTab = uitab(app.tabPaneApp);
            app.RacesdeunafuncinTab.Title = 'Raíces de una función';
            app.graficaFuncion = uiaxes(app.RacesdeunafuncinTab);
            title(app.graficaFuncion, 'Función y raíz aproximada por cada método')
            xlabel(app.graficaFuncion, 'X')
            ylabel(app.graficaFuncion, 'Y')
            zlabel(app.graficaFuncion, 'Z')
            app.EncontrarlasracesdeLabel = uilabel(app.RacesdeunafuncinTab);
            app.EncontrarlasracesdeLabel.Text = 'Encontrar las raíces de:';
            app.FuncinEditFieldLabel = uilabel(app.RacesdeunafuncinTab);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.funcion = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.AproximacinInicialLabel = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel.Text = 'Aproximación Inicial';
            app.x0 = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.NewtonRaphsonLabel = uilabel(app.RacesdeunafuncinTab);
            app.NewtonRaphsonLabel.Text = 'Newton-Raphson';
            app.ToleranciaLabel = uilabel(app.RacesdeunafuncinTab);
            app.ToleranciaLabel.Text = 'Tolerancia';
            app.tol = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.ToleranciaLabel_2 = uilabel(app.RacesdeunafuncinTab);
            app.ToleranciaLabel_2.Text = 'Max. Iteraciones';
            app.maxIter = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.CalcularButton = uibutton(app.RacesdeunafuncinTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.SecanteLabel = uilabel(app.RacesdeunafuncinTab);
            app.SecanteLabel.Text = 'Secante';
            app.AproximacinInicialLabel_2 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_2.Text = 'Xi';
            app.xi = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.ToleranciaLabel_3 = uilabel(app.RacesdeunafuncinTab);
            app.ToleranciaLabel_3.Text = 'Xi-1';
            app.xi_menos_1 = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.BiseccinLabel = uilabel(app.RacesdeunafuncinTab);
            app.BiseccinLabel.Text = 'Bisección';
            app.RegulaFalsiLabel = uilabel(app.RacesdeunafuncinTab);
            app.RegulaFalsiLabel.Text = 'Regula-Falsi';
            app.AproximacinInicialLabel_3 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_3.Text = 'a';
            app.a = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.AproximacinInicialLabel_4 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_4.Text = 'b';
            app.b = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.AproximacinInicialLabel_5 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_5.Text = 'b';
            app.b_2 = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.AproximacinInicialLabel_6 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_6.Text = 'a';
            app.a_2 = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.SIstemasdeecuacineslinealesTab = uitab(app.tabPaneApp);
            app.SIstemasdeecuacineslinealesTab.Title = 'SIstemas de ecuaciónes lineales';
            app.DerivadaNumricaTab = uitab(app.tabPaneApp);
            app.DerivadaNumricaTab.Title = 'Derivada Numérica';
            app.UIAxes = uiaxes(app.DerivadaNumricaTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FuncionLabel = uilabel(app.DerivadaNumricaTab);
            app.FuncionLabel.Text = 'Funcion:';
            app.FuncionEditField = uieditfield(app.DerivadaNumricaTab, 'text');
            app.xiEditFieldLabel = uilabel(app.DerivadaNumricaTab);
            app.xiEditFieldLabel.Text = 'xi:';
            app.xiEditField = uieditfield(app.DerivadaNumricaTab, 'numeric');
            app.hEditFieldLabel = uilabel(app.DerivadaNumricaTab);
            app.hEditFieldLabel.Text = 'h:';
            app.hEditField = uieditfield(app.DerivadaNumricaTab, 'numeric');
            app.CalcularDerivadasButton = uibutton(app.DerivadaNumricaTab, 'push');
            app.CalcularDerivadasButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularDerivadasButtonPushed, true);
            app.CalcularDerivadasButton.Text = 'Calcular Derivadas';
            app.ProgresivaLabel = uilabel(app.DerivadaNumricaTab);
            app.ProgresivaLabel.Text = 'Progresiva:';
            app.RegresivaLabel = uilabel(app.DerivadaNumricaTab);
            app.RegresivaLabel.Text = 'Regresiva:';
            app.CentralLabel = uilabel(app.DerivadaNumricaTab);
            app.CentralLabel.Text = 'Central:';
            app.OrdenDropDownLabel = uilabel(app.DerivadaNumricaTab);
            app.OrdenDropDownLabel.Text = 'Orden:';
            app.OrdenDropDown = uidropdown(app.DerivadaNumricaTab);
            app.OrdenDropDown.Items = {'1', '2', '3'};
            app.OrdenDropDown.Value = '1';
            app.IntegracinNumricaTab = uitab(app.tabPaneApp);
            app.IntegracinNumricaTab.SizeChangedFcn = createCallbackFcn(app, @CalcularTrapecioButtonPushed, true);
            app.IntegracinNumricaTab.Title = 'Integración Numérica';
            app.graficoTrapecio = uiaxes(app.IntegracinNumricaTab);
            xlabel(app.graficoTrapecio, 'X')
            ylabel(app.graficoTrapecio, 'Y')
            zlabel(app.graficoTrapecio, 'Z')
            app.IntegracinnumricaLabel = uilabel(app.IntegracinNumricaTab);
            app.IntegracinnumricaLabel.Text = 'Integración numérica ';
            app.CalcularTrapecioButton = uibutton(app.IntegracinNumricaTab, 'push');
            app.CalcularTrapecioButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularTrapecioButtonPushed, true);
            app.CalcularTrapecioButton.Text = 'Trapecio';
            app.CalcularSimpsonButton = uibutton(app.IntegracinNumricaTab, 'push');
            app.CalcularSimpsonButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularSimpsonButtonPushed, true);
            app.CalcularSimpsonButton.Text = 'Regla de Simpson';
            app.CalcularRectanguloButton = uibutton(app.IntegracinNumricaTab, 'push');
            app.CalcularRectanguloButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularRectanguloButtonPushed, true);
            app.CalcularRectanguloButton.Text = 'Riemman';
            app.CalcularporelmtododeLabel = uilabel(app.IntegracinNumricaTab);
            app.CalcularporelmtododeLabel.Text = 'Calcular por el método de:';
            app.label = uilabel(app.IntegracinNumricaTab);
            app.label.Text = 'Resultado:';
            app.resultadoLabel = uilabel(app.IntegracinNumricaTab);
            app.resultadoLabel.Text = '';
            app.AproximacinInicialLabel_7 = uilabel(app.IntegracinNumricaTab);
            app.AproximacinInicialLabel_7.Text = 'Función integral:';
            app.funcion_integral = uieditfield(app.IntegracinNumricaTab, 'text');
            app.LmiteinferioraEditFieldLabel = uilabel(app.IntegracinNumricaTab);
            app.LmiteinferioraEditFieldLabel.Text = 'Límite inferior a';
            app.a_limite = uieditfield(app.IntegracinNumricaTab, 'numeric');
            app.b_limite = uieditfield(app.IntegracinNumricaTab, 'numeric');
            app.LmiteinferiorbLabel = uilabel(app.IntegracinNumricaTab);
            app.LmiteinferiorbLabel.Text = 'Límite inferior b';
            app.LmiteinferiorbLabel_2 = uilabel(app.IntegracinNumricaTab);
            app.LmiteinferiorbLabel_2.Text = 'Número de intervalos';
            app.n_intervalo = uieditfield(app.IntegracinNumricaTab, 'numeric');
            app.EcuacionesDiferencialesTab = uitab(app.tabPaneApp);
            app.EcuacionesDiferencialesTab.Title = 'Ecuaciones Diferenciales';
            app.EulerMejoradoAxes = uiaxes(app.EcuacionesDiferencialesTab);
            title(app.EulerMejoradoAxes, 'Title')
            xlabel(app.EulerMejoradoAxes, 'X')
            ylabel(app.EulerMejoradoAxes, 'Y')
            zlabel(app.EulerMejoradoAxes, 'Z')
            app.EulerAxes = uiaxes(app.EcuacionesDiferencialesTab);
            title(app.EulerAxes, 'Title')
            xlabel(app.EulerAxes, 'X')
            ylabel(app.EulerAxes, 'Y')
            zlabel(app.EulerAxes, 'Z')
            app.FuncinfxyEditFieldLabel = uilabel(app.EcuacionesDiferencialesTab);
            app.FuncinfxyEditFieldLabel.Text = 'Función f(x, y)';
            app.edo = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            app.EulerMejoradoHeunLabel = uilabel(app.EcuacionesDiferencialesTab);
            app.EulerMejoradoHeunLabel.Text = 'Euler Mejorado (Heun)';
            app.x0EditFieldLabel = uilabel(app.EcuacionesDiferencialesTab);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0heun = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            app.y0EditFieldLabel = uilabel(app.EcuacionesDiferencialesTab);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0heun = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            app.hEditField_2Label = uilabel(app.EcuacionesDiferencialesTab);
            app.hEditField_2Label.Text = 'h';
            app.h = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            app.HastaquexEditFieldLabel = uilabel(app.EcuacionesDiferencialesTab);
            app.HastaquexEditFieldLabel.Text = 'Hasta que x =';
            app.x_final = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            app.CalcularEulerMejoradoButton = uibutton(app.EcuacionesDiferencialesTab, 'push');
            app.CalcularEulerMejoradoButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularEulerMejorado, true);
            app.CalcularEulerMejoradoButton.Text = 'Calcular Euler Mejorado';
            app.EulerMejoradoTable = uitable(app.EcuacionesDiferencialesTab);
            app.EulerMejoradoTable.ColumnName = {'Iteracion'; 'x'; 'y_aprox'; 'k1'; 'k2'};
            app.EulerMejoradoTable.RowName = {};
            app.EulerLabel = uilabel(app.EcuacionesDiferencialesTab);
            app.EulerLabel.Text = 'Euler';
            app.CalcularEulerButton = uibutton(app.EcuacionesDiferencialesTab, 'push');
            app.CalcularEulerButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularEulerButtonPushed, true);
            app.CalcularEulerButton.Text = 'Calcular Euler';
            app.EulerTable = uitable(app.EcuacionesDiferencialesTab);
            app.EulerTable.ColumnName = {'Iteracion'; 'x'; 'y_aprox'};
            app.EulerTable.RowName = {};
            app.FuncinfxyEditFieldLabel_2 = uilabel(app.EcuacionesDiferencialesTab);
            app.FuncinfxyEditFieldLabel_2.Text = 'Función f(x, y)';
            app.funcionEDO = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            app.x0EditFieldLabel_2 = uilabel(app.EcuacionesDiferencialesTab);
            app.x0EditFieldLabel_2.Text = 'x0';
            app.x0euler = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            app.y0EditFieldLabel_2 = uilabel(app.EcuacionesDiferencialesTab);
            app.y0EditFieldLabel_2.Text = 'y0';
            app.y0euler = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            app.hEditField_2Label_2 = uilabel(app.EcuacionesDiferencialesTab);
            app.hEditField_2Label_2.Text = 'h';
            app.h_2 = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            app.HastaquexEditFieldLabel_2 = uilabel(app.EcuacionesDiferencialesTab);
            app.HastaquexEditFieldLabel_2.Text = 'Hasta que x =';
            app.x_final_2 = uieditfield(app.EcuacionesDiferencialesTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMetNum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end