classdef appMetNum_BACKUP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        tabPaneApp                      matlab.ui.container.TabGroup
        RacesdeunafuncinTab             matlab.ui.container.Tab
        b                               matlab.ui.control.EditField
        AproximacinInicialLabel_4       matlab.ui.control.Label
        a                               matlab.ui.control.EditField
        AproximacinInicialLabel_3       matlab.ui.control.Label
        RegulaFalsiLabel                matlab.ui.control.Label
        BiseccinLabel                   matlab.ui.control.Label
        xi_menos_1                      matlab.ui.control.EditField
        ToleranciaLabel_3               matlab.ui.control.Label
        xi                              matlab.ui.control.EditField
        AproximacinInicialLabel_2       matlab.ui.control.Label
        SecanteLabel                    matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        maxIter                         matlab.ui.control.EditField
        ToleranciaLabel_2               matlab.ui.control.Label
        tol                             matlab.ui.control.EditField
        ToleranciaLabel                 matlab.ui.control.Label
        NewtonRaphsonLabel              matlab.ui.control.Label
        x0                              matlab.ui.control.EditField
        AproximacinInicialLabel         matlab.ui.control.Label
        funcion                         matlab.ui.control.EditField
        FuncinEditFieldLabel            matlab.ui.control.Label
        EncontrarlasracesdeLabel        matlab.ui.control.Label
        graficaFuncion                  matlab.ui.control.UIAxes
        SIstemasdeecuacineslinealesTab  matlab.ui.container.Tab
        DerivadaNumricaTab              matlab.ui.container.Tab
        IntegracinNumricaTab            matlab.ui.container.Tab
        N_trapecio                      matlab.ui.control.NumericEditField
        LmiteinferiorbLabel_2           matlab.ui.control.Label
        a_limite                        matlab.ui.control.NumericEditField
        LmiteinferioraEditFieldLabel    matlab.ui.control.Label
        LmiteinferiorbLabel             matlab.ui.control.Label
        b_limite                        matlab.ui.control.NumericEditField
        funcion_integral                matlab.ui.control.EditField
        AproximacinInicialLabel_5       matlab.ui.control.Label
        resultadoLabel                  matlab.ui.control.Label
        label                           matlab.ui.control.Label
        CalcularporelmtododeLabel       matlab.ui.control.Label
        CalcularRectanguloButtonPushed  matlab.ui.control.Button
        CalcularSimpsonButtonPushed     matlab.ui.control.Button
        CalcularTrapecioButtonPushed    matlab.ui.control.Button
        IntegracinnumricaLabel          matlab.ui.control.Label
        graficaFuncion_2                matlab.ui.control.UIAxes
        EcuacionesDiferencialesTab      matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % --- Configurar variable simbólica y entrada del usuario ---
            syms x
            f = str2sym(app.funcion.Value);                      % Convierte el texto en expresión simbólica
            df = diff(f, x);                               % Deriva simbólicamente
            % --- Convertir funciones simbólicas a anónimas ---
            f_anon = matlabFunction(f);                    % @(x) ...
            df_anon = matlabFunction(df);                  % @(x) ...
            
            % --- Obtener y convertir valores numéricos de los campos de la GUI ---
            % ¡Asegúrate de que estas conversiones se hacen antes de usarlas!
            x0_numerico = str2double(app.x0.Value); % Aproximación inicial para Newton-Raphson
            tol_numerico = str2double(app.tol.Value); % Tolerancia para todos los métodos
            
            maxIter_texto = app.maxIter.Value;
            maxIter_numerico_double = str2double(maxIter_texto);
            
            % 3. Validar que sea un número entero positivo
            if isnan(maxIter_numerico_double) || maxIter_numerico_double <= 0 || mod(maxIter_numerico_double, 1) ~= 0
                uialert(app.UIFigure, 'El "Máximo de Iteraciones" debe ser un número entero positivo. Por favor, corrija la entrada.', 'Error de Entrada');
                return; % Detiene la ejecución del callback si hay error
            end
            maxIter_entero = int64(maxIter_numerico_double); 
            
            % --- Ejecutar Newton-Raphson ---
            [raiz_newton, iteraciones_newton, error_relativo_newton] = newtonRaphson(f_anon, df_anon, x0_numerico, tol_numerico, maxIter_entero);
            
            % --- Obtener valores para el método de la secante ---
            x_i_secante = str2double(app.xi.Value); % Segunda aproximación inicial para Secante
            xi_1_secante = str2double(app.xi_menos_1.Value); % Primera aproximación inicial para Secante
            % --- Ejecutar Método de la Secante ---
            % Nota: La función metodoSecante no recibe df_anon.
            % Asegúrate de que tu función metodoSecante solo espera 5 argumentos:
            % (funcion_anonima, x_i_menos_1, x_i, tolerancia, max_iteraciones)
            [raiz_secante, iteraciones_secante, error_relativo_secante] = metodoSecante(f_anon, xi_1_secante, x_i_secante, tol_numerico, maxIter_entero);
            
            % --- Obtener valores para el método de la bisección ---
            a_biseccion = str2double(app.a.Value); % Límite inferior para Bisección
            b_biseccion = str2double(app.b.Value); % Límite superior para Bisección

            % --- Ejecutar Método de Bisección ---
            % Asegúrate de que tu función biseccion solo espera 5 argumentos:
            % (funcion_anonima, a, b, tolerancia, max_iteraciones)
            [raiz_biseccion, iteraciones_biseccion, error_relativo_biseccion] = biseccion(f_anon, a_biseccion, b_biseccion, tol_numerico, maxIter_entero);
            
            % --- Graficar función ---
            cla(app.graficaFuncion)                       % Limpia el gráfico anterior
            
            % Rango de la gráfica: ajustado para incluir todas las raíces y un poco más
            % Puedes ajustar este rango para que se adapte mejor a tus funciones.
            min_x_plot = min([x0_numerico - 10, raiz_newton - 2, raiz_secante - 2, raiz_biseccion - 2]);
            max_x_plot = max([x0_numerico + 10, raiz_newton + 2, raiz_secante + 2, raiz_biseccion + 2]);
            fplot(app.graficaFuncion, f, [min_x_plot, max_x_plot], 'LineWidth', 1.5, 'DisplayName', 'f(x)')
            hold(app.graficaFuncion, 'on')
            
            % --- Marcar y etiquetar raíz de Newton-Raphson ---
            y_raiz_newton = f_anon(raiz_newton);
            plot(app.graficaFuncion, raiz_newton, y_raiz_newton, 'ro', 'MarkerSize', 8, 'LineWidth', 2, 'DisplayName', 'Raíz Newton-Raphson')
            text(app.graficaFuncion, raiz_newton, y_raiz_newton, sprintf('  Newton-Raphson (%.5f)', raiz_newton), ...
                 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'left', 'Color', 'red', 'FontSize', 15);
            
            % --- Marcar y etiquetar raíz de la Secante ---
            y_raiz_secante = f_anon(raiz_secante);
            plot(app.graficaFuncion, raiz_secante, y_raiz_secante, 'bs', 'MarkerSize', 8, 'LineWidth', 2, 'DisplayName', 'Raíz Secante')
            text(app.graficaFuncion, raiz_secante, y_raiz_secante, sprintf('  Secante (%.5f)', raiz_secante), ...
                 'VerticalAlignment', 'top', 'HorizontalAlignment', 'left', 'Color', 'blue', 'FontSize', 15);
            
            % --- Marcar y etiquetar raíz de la Bisección ---
            y_raiz_biseccion = f_anon(raiz_biseccion);
            plot(app.graficaFuncion, raiz_biseccion, y_raiz_biseccion, 'gd', 'MarkerSize', 8, 'LineWidth', 2, 'DisplayName', 'Raíz Bisección') % 'gd' para un diamante verde
            text(app.graficaFuncion, raiz_biseccion, y_raiz_biseccion, sprintf('  Bisección (%.5f)', raiz_biseccion), ...
                 'VerticalAlignment', 'middle', 'HorizontalAlignment', 'right', 'Color', 'green', 'FontSize', 15); % Ajusta la alineación si se superpone
            
            % --- Estética del gráfico ---
            grid(app.graficaFuncion, 'on')
            xlabel(app.graficaFuncion, 'x')
            ylabel(app.graficaFuncion, 'f(x)')
            legend(app.graficaFuncion, 'show', 'Location', 'best'); % Muestra la leyenda
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.tabPaneApp = uitabgroup(app.UIFigure);
            app.tabPaneApp.Tag = 'tabPaneApp';
            app.RacesdeunafuncinTab = uitab(app.tabPaneApp);
            app.RacesdeunafuncinTab.Title = 'Raíces de una función';
            app.graficaFuncion = uiaxes(app.RacesdeunafuncinTab);
            title(app.graficaFuncion, 'Función y raíz aproximada por cada método')
            xlabel(app.graficaFuncion, 'X')
            ylabel(app.graficaFuncion, 'Y')
            zlabel(app.graficaFuncion, 'Z')
            app.EncontrarlasracesdeLabel = uilabel(app.RacesdeunafuncinTab);
            app.EncontrarlasracesdeLabel.Text = 'Encontrar las raíces de:';
            app.FuncinEditFieldLabel = uilabel(app.RacesdeunafuncinTab);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.funcion = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.AproximacinInicialLabel = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel.Text = 'Aproximación Inicial';
            app.x0 = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.NewtonRaphsonLabel = uilabel(app.RacesdeunafuncinTab);
            app.NewtonRaphsonLabel.Text = 'Newton-Raphson';
            app.ToleranciaLabel = uilabel(app.RacesdeunafuncinTab);
            app.ToleranciaLabel.Text = 'Tolerancia';
            app.tol = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.ToleranciaLabel_2 = uilabel(app.RacesdeunafuncinTab);
            app.ToleranciaLabel_2.Text = 'Max. Iteraciones';
            app.maxIter = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.CalcularButton = uibutton(app.RacesdeunafuncinTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.SecanteLabel = uilabel(app.RacesdeunafuncinTab);
            app.SecanteLabel.Text = 'Secante';
            app.AproximacinInicialLabel_2 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_2.Text = 'Xi';
            app.xi = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.ToleranciaLabel_3 = uilabel(app.RacesdeunafuncinTab);
            app.ToleranciaLabel_3.Text = 'Xi-1';
            app.xi_menos_1 = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.BiseccinLabel = uilabel(app.RacesdeunafuncinTab);
            app.BiseccinLabel.Text = 'Bisección';
            app.RegulaFalsiLabel = uilabel(app.RacesdeunafuncinTab);
            app.RegulaFalsiLabel.Text = 'Regula-Falsi';
            app.AproximacinInicialLabel_3 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_3.Text = 'a';
            app.a = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.AproximacinInicialLabel_4 = uilabel(app.RacesdeunafuncinTab);
            app.AproximacinInicialLabel_4.Text = 'b';
            app.b = uieditfield(app.RacesdeunafuncinTab, 'text');
            app.SIstemasdeecuacineslinealesTab = uitab(app.tabPaneApp);
            app.SIstemasdeecuacineslinealesTab.Title = 'SIstemas de ecuaciónes lineales';
            app.DerivadaNumricaTab = uitab(app.tabPaneApp);
            app.DerivadaNumricaTab.Title = 'Derivada Numérica';
            app.IntegracinNumricaTab = uitab(app.tabPaneApp);
            app.IntegracinNumricaTab.Title = 'Integración Numérica';
            app.graficaFuncion_2 = uiaxes(app.IntegracinNumricaTab);
            xlabel(app.graficaFuncion_2, 'X')
            ylabel(app.graficaFuncion_2, 'Y')
            zlabel(app.graficaFuncion_2, 'Z')
            app.IntegracinnumricaLabel = uilabel(app.IntegracinNumricaTab);
            app.IntegracinnumricaLabel.Text = 'Integración numérica ';
            app.CalcularTrapecioButtonPushed = uibutton(app.IntegracinNumricaTab, 'push');
            app.CalcularTrapecioButtonPushed.Text = 'Trapecio';
            app.CalcularSimpsonButtonPushed = uibutton(app.IntegracinNumricaTab, 'push');
            app.CalcularSimpsonButtonPushed.Text = 'Regla de Simpson';
            app.CalcularRectanguloButtonPushed = uibutton(app.IntegracinNumricaTab, 'push');
            app.CalcularRectanguloButtonPushed.Text = 'Riemman';
            app.CalcularporelmtododeLabel = uilabel(app.IntegracinNumricaTab);
            app.CalcularporelmtododeLabel.Text = 'Calcular por el método de:';
            app.label = uilabel(app.IntegracinNumricaTab);
            app.label.Text = 'Resultado:';
            app.resultadoLabel = uilabel(app.IntegracinNumricaTab);
            app.resultadoLabel.Text = '';
            app.AproximacinInicialLabel_5 = uilabel(app.IntegracinNumricaTab);
            app.AproximacinInicialLabel_5.Text = 'Función integral:';
            app.funcion_integral = uieditfield(app.IntegracinNumricaTab, 'text');
            app.b_limite = uieditfield(app.IntegracinNumricaTab, 'numeric');
            app.LmiteinferiorbLabel = uilabel(app.IntegracinNumricaTab);
            app.LmiteinferiorbLabel.Text = 'Límite inferior b';
            app.LmiteinferioraEditFieldLabel = uilabel(app.IntegracinNumricaTab);
            app.LmiteinferioraEditFieldLabel.Text = 'Límite inferior a';
            app.a_limite = uieditfield(app.IntegracinNumricaTab, 'numeric');
            app.LmiteinferiorbLabel_2 = uilabel(app.IntegracinNumricaTab);
            app.LmiteinferiorbLabel_2.Text = 'Número de intervalos';
            app.N_trapecio = uieditfield(app.IntegracinNumricaTab, 'numeric');
            app.EcuacionesDiferencialesTab = uitab(app.tabPaneApp);
            app.EcuacionesDiferencialesTab.Title = 'Ecuaciones Diferenciales';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMetNum_BACKUP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end